classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ResetButton                  matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        x_0EditField_3               matlab.ui.control.NumericEditField
        x_0EditField_3Label          matlab.ui.control.Label
        newtonInstructions           matlab.ui.control.TextArea
        x_0EditField_2               matlab.ui.control.NumericEditField
        x_0EditField_2Label          matlab.ui.control.Label
        ComputeButton                matlab.ui.control.Button
        goldenInstructions           matlab.ui.control.TextArea
        MethodSwitch                 matlab.ui.control.Switch
        MethodSwitchLabel            matlab.ui.control.Label
        MinorMaxSwitch               matlab.ui.control.Switch
        MinorMaxSwitchLabel          matlab.ui.control.Label
        DeltaEditField               matlab.ui.control.NumericEditField
        DeltaLabel                   matlab.ui.control.Label
        x_1EditField_2               matlab.ui.control.NumericEditField
        IntervalsLabel               matlab.ui.control.Label
        PlotFunctionButton           matlab.ui.control.Button
        FunctionEditField            matlab.ui.control.EditField
        FunctionEditFieldLabel       matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        IterationsLabel              matlab.ui.control.Label
        iterations                   matlab.ui.control.TextArea
        YLabel                       matlab.ui.control.Label
        functionResult               matlab.ui.control.TextArea
        point                        matlab.ui.control.TextArea
        PointLabel                   matlab.ui.control.Label
        y2Int                        matlab.ui.control.EditField
        y_2EditFieldLabel            matlab.ui.control.Label
        y1Int                        matlab.ui.control.EditField
        y_1Label                     matlab.ui.control.Label
        RefineGraphtoIntervalsLabel  matlab.ui.control.Label
        x2Int                        matlab.ui.control.EditField
        x_2EditFieldLabel            matlab.ui.control.Label
        x1Int                        matlab.ui.control.EditField
        x_1EditFieldLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function clear(app)
            app.FunctionEditField.Value = '';
            app.DeltaEditField.Value = 0;
            app.x_0EditField_2.Value = 0;
            app.x_0EditField_3.Value = 0;
            cla(app.UIAxes);
            app.x1Int.Value = '';
            app.x2Int.Value = '';
            app.y1Int.Value = '';
            app.y2Int.Value = '';
            app.x_1EditField_2.Value = 0;
            app.point.Value = '';
            app.iterations.Value = '';
            app.functionResult.Value = '';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PlotFunctionButton, UIAxes
        function PlotFunctionButtonPushed(app, event)
               app.UIAxes.XLimMode = "auto";
               app.UIAxes.YLimMode = "auto";
               
               app.TextArea.Visible = 'off';
               input = app.FunctionEditField.Value;
               if(isempty(input))
                    return
               end
               try
                     fx = str2func(['@(x)' input]);
               catch
                     app.TextArea.Visible = 'on';
               end
               fx = str2func(['@(x)' input]);
               label = strcat('y= ', input);
               ax = app.UIAxes;
               ax.UserData = struct("function", fx);
               fplot(ax, fx);
               title(ax, label);
%                app.MethodSwitch.Value = "Golden";
               if(app.MethodSwitch.Value == "Golden")
                app.newtonInstructions.Visible = 'off';
                app.goldenInstructions.Visible = 'on';
                app.x_0EditField_2.Visible = 'on';
                app.x_0EditField_2Label.Visible = 'on';
                app.x_1EditField_2.Visible = 'on';
                app.IntervalsLabel.Visible = 'on';
                app.MinorMaxSwitch.Visible = 'on';
                app.ComputeButton.Visible = 'on';
                app.x_0EditField_3.Visible = 'off';
                app.x_0EditField_3Label.Visible = 'off';
                app.DeltaEditField.Visible = 'on';
                app.DeltaLabel.Visible = 'on';
                app.ComputeButton.Visible = 'on';
                app.MethodSwitch.Visible = 'on';
                app.MethodSwitchLabel.Visible = 'on';
               else
                app.newtonInstructions.Visible = 'on';
                app.goldenInstructions.Visible = 'off';
                app.x_0EditField_2.Visible = 'off';
                app.x_0EditField_2Label.Visible = 'off';
                app.x_1EditField_2.Visible = 'off';
                app.IntervalsLabel.Visible = 'off';
                app.MinorMaxSwitch.Visible = 'on';
                app.ComputeButton.Visible = 'on';
                app.x_0EditField_3.Visible = 'on';
                app.x_0EditField_3Label.Visible = 'on';
                app.DeltaEditField.Visible = 'on';
                app.DeltaLabel.Visible = 'on';
                app.ComputeButton.Visible = 'on';
                app.MethodSwitch.Visible = 'on';
                app.MethodSwitchLabel.Visible = 'on';
               end
        end

        % Callback function
        function FunctionEditFieldValueChanged(app, event)

        end

        % Value changed function: x1Int, x2Int, y1Int, y2Int
        function x_1_Interval(app, event)
            x1 = str2num(app.x1Int.Value);
            x2 = str2num(app.x2Int.Value);
            y1 = str2num(app.y1Int.Value);
            y2 = str2num(app.y2Int.Value);
            if(~isempty(app.x2Int.Value) && ~isempty(app.x1Int.Value))
                app.UIAxes.XLim = [x1 x2];
            end
            if(~isempty(app.y1Int.Value) && ~isempty(app.y2Int.Value))
                app.UIAxes.YLim = [y1 y2];
            end
        end

        % Button pushed function: ComputeButton
        function callFunctions(app, event)
            if(app.MethodSwitch.Value == "Golden")
                    x1 = app.x_0EditField_2.Value;
                    x2 = app.x_1EditField_2.Value;
                    delta = app.DeltaEditField.Value;
                    choice = 10;
                    fx = app.UIAxes.UserData.function;
                    if(app.MinorMaxSwitch.Value == "Min")
                        choice = 1;
                    elseif(app.MinorMaxSwitch.Value == "Max")
                        choice = 2;
                    end
                    [x, y, i] = goldenSection_W(x1, x2, fx, delta, choice);
                   string1 = num2str(x);
                   string2 = num2str(y);
                   string3 = num2str(i);
                   gPoint = strcat("(", string1,", ", string2, ")");
                   app.iterations.Value = string3;
                   app.point.Value = gPoint;
                   app.functionResult.Value = string2;
             elseif(app.MethodSwitch.Value == "Newton")
                    x = app.x_0EditField_3.Value;
                    delta = app.DeltaEditField.Value;
                    choice = 10;
                    fx = app.UIAxes.UserData.function;
                    if(app.MinorMaxSwitch.Value == "Min")
                        choice = 1;
                    elseif(app.MinorMaxSwitch.Value == "Max")
                        choice = 2;
                    end
                    f = app.UIAxes.UserData.function;
                    [x, y, i] = newtonsMethod_W(x, f, delta);
                    string1 = num2str(x);
                    string2 = num2str(y);
                    string3 = num2str(i);
                    gPoint = strcat("(", string1,", ", string2, ")");
                    app.iterations.Value = string3;
                    app.point.Value = gPoint;
                    app.functionResult.Value = string2;
            end
        end

        % Value changed function: MethodSwitch
        function SwitchMethod(app, event)
            value = app.MethodSwitch.Value;
            if(app.newtonInstructions.Visible == "on")
                app.newtonInstructions.Visible = 'off';
                app.goldenInstructions.Visible = 'on';
                app.x_0EditField_2.Visible = 'on';
                app.x_0EditField_2Label.Visible = 'on';
                app.x_1EditField_2.Visible = 'on';
                app.IntervalsLabel.Visible = 'on';
                app.DeltaEditField.Visible = 'on';
                app.MinorMaxSwitch.Visible = 'on';
                app.ComputeButton.Visible = 'on';
                app.x_0EditField_3.Visible = 'off';
                app.x_0EditField_3Label.Visible = 'off';
            else
                app.newtonInstructions.Visible = 'on';
                app.goldenInstructions.Visible = 'off';
                app.x_0EditField_2.Visible = 'off';
                app.x_0EditField_2Label.Visible = 'off';
                app.x_1EditField_2.Visible = 'off';
                app.IntervalsLabel.Visible = 'off';
                app.DeltaEditField.Visible = 'on';
                app.MinorMaxSwitch.Visible = 'on';
                app.ComputeButton.Visible = 'on';
                app.x_0EditField_3.Visible = 'on';
                app.x_0EditField_3Label.Visible = 'on';
            end
        end

        % Button pushed function: ResetButton
        function resetButton(app, event)
            clear(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {746, 746};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {399, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionEditFieldLabel = uilabel(app.LeftPanel);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.LeftPanel, 'text');
            app.FunctionEditField.Placeholder = 'f(x)';
            app.PlotFunctionButton = uibutton(app.LeftPanel, 'push');
            app.PlotFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton.Text = 'Plot Function';
            app.IntervalsLabel = uilabel(app.LeftPanel);
            app.IntervalsLabel.Interpreter = 'latex';
            app.IntervalsLabel.Visible = 'off';
            app.IntervalsLabel.Text = '$x_{1}';
            app.x_1EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.x_1EditField_2.Visible = 'off';
            app.DeltaLabel = uilabel(app.LeftPanel);
            app.DeltaLabel.Visible = 'off';
            app.DeltaLabel.Text = 'Delta (ϕ)';
            app.DeltaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DeltaEditField.Visible = 'off';
            app.MinorMaxSwitchLabel = uilabel(app.LeftPanel);
            app.MinorMaxSwitchLabel.Visible = 'off';
            app.MinorMaxSwitchLabel.Text = 'Min or Max ';
            app.MinorMaxSwitch = uiswitch(app.LeftPanel, 'slider');
            app.MinorMaxSwitch.Items = {'Min', 'Max'};
            app.MinorMaxSwitch.Visible = 'off';
            app.MinorMaxSwitch.Value = 'Min';
            app.MethodSwitchLabel = uilabel(app.LeftPanel);
            app.MethodSwitchLabel.Visible = 'off';
            app.MethodSwitchLabel.Text = 'Method';
            app.MethodSwitch = uiswitch(app.LeftPanel, 'slider');
            app.MethodSwitch.Items = {'Golden', 'Newton'};
            app.MethodSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchMethod, true);
            app.MethodSwitch.Visible = 'off';
            app.MethodSwitch.Value = 'Golden';
            app.goldenInstructions = uitextarea(app.LeftPanel);
            app.goldenInstructions.Visible = 'off';
            app.goldenInstructions.Value = {'Golden Method : Technique for finding a specific value of a function inside a specified interval'; ''; 'x1 = b - ((b-a)/ϕ)            x2 = a + ((b-a)/ϕ)'; 'y1 = fx(x1)                     y2 = fx(x2)'; ''};
            app.ComputeButton = uibutton(app.LeftPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @callFunctions, true);
            app.ComputeButton.Visible = 'off';
            app.ComputeButton.Text = 'Compute';
            app.x_0EditField_2Label = uilabel(app.LeftPanel);
            app.x_0EditField_2Label.Interpreter = 'latex';
            app.x_0EditField_2Label.Visible = 'off';
            app.x_0EditField_2Label.Text = '$x_{0}';
            app.x_0EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.x_0EditField_2.Visible = 'off';
            app.newtonInstructions = uitextarea(app.LeftPanel);
            app.newtonInstructions.Visible = 'off';
            app.newtonInstructions.Value = {'Newton  Method : Root-finding algorithm which produces approximations with roots of a real-valued function.'; ''; 'a = 0.3943'; 'xk = x - (a * (fprime1(x) / fprime2(x)))'; 'y = f(xk)'};
            app.x_0EditField_3Label = uilabel(app.LeftPanel);
            app.x_0EditField_3Label.Interpreter = 'latex';
            app.x_0EditField_3Label.Visible = 'off';
            app.x_0EditField_3Label.Text = '$x_{0}';
            app.x_0EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.x_0EditField_3.Visible = 'off';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'The input by user is not a function!'};
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButton, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.x_1EditFieldLabel = uilabel(app.RightPanel);
            app.x_1EditFieldLabel.Interpreter = 'latex';
            app.x_1EditFieldLabel.Text = '$x_{1}';
            app.x1Int = uieditfield(app.RightPanel, 'text');
            app.x1Int.ValueChangedFcn = createCallbackFcn(app, @x_1_Interval, true);
            app.x1Int.Placeholder = 'ex: -1';
            app.x_2EditFieldLabel = uilabel(app.RightPanel);
            app.x_2EditFieldLabel.Interpreter = 'latex';
            app.x_2EditFieldLabel.Text = '$x_{2}';
            app.x2Int = uieditfield(app.RightPanel, 'text');
            app.x2Int.ValueChangedFcn = createCallbackFcn(app, @x_1_Interval, true);
            app.x2Int.Placeholder = 'ex: 1';
            app.RefineGraphtoIntervalsLabel = uilabel(app.RightPanel);
            app.RefineGraphtoIntervalsLabel.Text = 'Refine Graph to Intervals:';
            app.y_1Label = uilabel(app.RightPanel);
            app.y_1Label.Interpreter = 'latex';
            app.y_1Label.Text = '$y_{1}';
            app.y1Int = uieditfield(app.RightPanel, 'text');
            app.y1Int.ValueChangedFcn = createCallbackFcn(app, @x_1_Interval, true);
            app.y1Int.Placeholder = 'ex:-10';
            app.y_2EditFieldLabel = uilabel(app.RightPanel);
            app.y_2EditFieldLabel.Interpreter = 'latex';
            app.y_2EditFieldLabel.Text = '$y_{2}';
            app.y2Int = uieditfield(app.RightPanel, 'text');
            app.y2Int.ValueChangedFcn = createCallbackFcn(app, @x_1_Interval, true);
            app.y2Int.Placeholder = 'ex: 10';
            app.PointLabel = uilabel(app.RightPanel);
            app.PointLabel.Text = 'Point';
            app.point = uitextarea(app.RightPanel);
            app.point.Editable = 'off';
            app.functionResult = uitextarea(app.RightPanel);
            app.functionResult.Editable = 'off';
            app.YLabel = uilabel(app.RightPanel);
            app.YLabel.Text = 'Y';
            app.iterations = uitextarea(app.RightPanel);
            app.iterations.Editable = 'off';
            app.IterationsLabel = uilabel(app.RightPanel);
            app.IterationsLabel.Text = 'Iterations';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end